classdef NLX_Event_Finder_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NLXEventFinderUIFigure  matlab.ui.Figure
        Toolbar                 matlab.ui.container.Toolbar
        foldSel                 matlab.ui.container.toolbar.PushTool
        EventListListBoxLabel   matlab.ui.control.Label
        EventList               matlab.ui.control.ListBox
        StatusUpdateLabel       matlab.ui.control.Label
        statusMess              matlab.ui.control.TextArea
        DateLabel               matlab.ui.control.Label
        StartTimeLabel          matlab.ui.control.Label
        dateField               matlab.ui.control.EditField
        startTfield             matlab.ui.control.EditField
        EventDisplayPanel       matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        UIAxes                  matlab.ui.control.UIAxes
        PlotEvents              matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        ContextMain             matlab.ui.container.ContextMenu
        Menu                    matlab.ui.container.Menu
        Menu2                   matlab.ui.container.Menu
        ContextEditMessage      matlab.ui.container.ContextMenu
        Back2top                matlab.ui.container.Menu
        Clear                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        eventLoc % Location for event files
        nevLIST
        timestamps
        ttl
        header
        evText
        EventDat
    end
    
    methods (Access = private)
        
        function updateMessage(app , newMessage)
            curTime = datestr(now);
            app.statusMess.Value = [app.statusMess.Value ;...
                ' ' , curTime , ' ' , newMessage];
            
        end
        
        function processEventFile(app)
            
            % 1. Determine number of Start Recording fields
            % 2. Determine number of TTL flags
            % 3. Calculate number time between flags
            
            % Populate table
            app.startTfield.Value
            app.EventDat
            test = 1;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.statusMess.Value = 'Status UPDATES:';
        end

        % Clicked callback: foldSel
        function foldSelClicked(app, event)
            
            nevCheck = 1;
            while nevCheck
                [app.eventLoc] = uigetdir('title','Select folder with Events file');
                
                cd(app.eventLoc)
                
                nevDIRa = dir("*.nev");
                nevDIRb = {nevDIRa.name};
                
                if isempty(nevDIRb)
                    continue
                else
                    nevCheck = 0;
                end
            end
            
            app.nevLIST = nevDIRb;
            app.EventList.Items = ['...',app.nevLIST];
            app.EventList.Enable = 'on';
            
        end

        % Value changed function: EventList
        function EventListValueChanged(app, event)
            curEvent = app.EventList.Value;
            
            % Update message
            updateMessage(app , ['LOAD: ' , curEvent])
            
            % Check which Event file the user clicked on
            if strcmp(curEvent,'...')
                % If the first value than return
                return
            else
                % If an available event file, then load using NLX reader
                [Timestamps, ~, TTLs, ~, evMessage, evHeader] =...
                    Nlx2MatEV(curEvent, [1 1 1 1 1], 1, 1, [] );
                
                % Extract timestamps, TTLs, and header
                app.timestamps = Timestamps;
                app.ttl = TTLs;
                app.header = evHeader;
                app.evText = evMessage;
                
                app.EventDat.ts = app.timestamps;
                app.EventDat.ttl = app.ttl;
                app.EventDat.header = app.header;
                app.EventDat.tInfo = app.evText;
                
            end
            
            % Extract start time and date
            tmpDate = app.header(contains(app.header,'TimeCreated'));
            dateParts = strsplit(tmpDate{1},' ');
            
            app.dateField.Value = dateParts{2};
            app.startTfield.Value = dateParts{3};
            
            % Update message with load and availability of TTLs
            updateMessage(app , ['Date: ' , app.dateField.Value])
            updateMessage(app , ['Start Time: ' , app.startTfield.Value])
            
            if (length(app.timestamps) == 1) && app.timestamps == 0
                uialert(app.NLXEventFinderUIFigure, 'NO Timestamps!',...
                    "Timestamp Acquisition", "Icon","warning")
                updateMessage(app , 'NO Timestamps!')
            else
                % Count number of timestamps
                numberTSs = num2str(length(app.timestamps));
                % Update message
                updateMessage(app , ['Number of timestamps: ', numberTSs])
                % Activate Plot button
                updateMessage(app , 'Plot Button Activated')
                app.PlotEvents.Enable = 'on';
                
                processEventFile(app)
                
            end
            
        end

        % Menu selected function: Back2top
        function Back2topMenuSelected(app, event)
            scroll(app.statusMess, 'top')
        end

        % Menu selected function: Clear
        function ClearMenuSelected(app, event)
            app.statusMess.Value = 'Status UPDATES:';
        end

        % Button pushed function: PlotEvents
        function PlotEventsButtonPushed(app, event)
            buttonOON = 1;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NLXEventFinderUIFigure = uifigure('Visible', 'off');
            app.NLXEventFinderUIFigure.Name = 'NLX Event Finder';
            app.NLXEventFinderUIFigure.Icon = 'icon.png';
            app.Toolbar = uitoolbar(app.NLXEventFinderUIFigure);
            app.foldSel = uipushtool(app.Toolbar);
            app.foldSel.Tooltip = {'Open Experiment folder location.'};
            app.foldSel.ClickedCallback = createCallbackFcn(app, @foldSelClicked, true);
            app.foldSel.Icon = 'FolderOpenIconF.png';
            app.EventListListBoxLabel = uilabel(app.NLXEventFinderUIFigure);
            app.EventListListBoxLabel.Text = 'Event List';
            app.EventList = uilistbox(app.NLXEventFinderUIFigure);
            app.EventList.Items = {'...'};
            app.EventList.ValueChangedFcn = createCallbackFcn(app, @EventListValueChanged, true);
            app.EventList.Enable = 'off';
            app.EventList.Value = '...';
            app.StatusUpdateLabel = uilabel(app.NLXEventFinderUIFigure);
            app.StatusUpdateLabel.Text = {'Status '; 'Update'};
            app.statusMess = uitextarea(app.NLXEventFinderUIFigure);
            app.statusMess.Editable = 'off';
            app.DateLabel = uilabel(app.NLXEventFinderUIFigure);
            app.DateLabel.Text = 'Date:';
            app.StartTimeLabel = uilabel(app.NLXEventFinderUIFigure);
            app.StartTimeLabel.Text = 'Start Time:';
            app.dateField = uieditfield(app.NLXEventFinderUIFigure, 'text');
            app.startTfield = uieditfield(app.NLXEventFinderUIFigure, 'text');
            app.EventDisplayPanel = uipanel(app.NLXEventFinderUIFigure);
            app.EventDisplayPanel.Title = 'Event Display';
            app.GridLayout = uigridlayout(app.EventDisplayPanel);
            app.UIAxes = uiaxes(app.GridLayout);
            app.PlotEvents = uibutton(app.NLXEventFinderUIFigure, 'push');
            app.PlotEvents.ButtonPushedFcn = createCallbackFcn(app, @PlotEventsButtonPushed, true);
            app.PlotEvents.Enable = 'off';
            app.PlotEvents.Text = 'Plot Events';
            app.UITable = uitable(app.NLXEventFinderUIFigure);
            app.UITable.ColumnName = {'Records'; 'Time Start'; 'Duration'; 'Number of TTLs'};
            app.UITable.RowName = {};
            app.ContextMain = uicontextmenu(app.NLXEventFinderUIFigure);
            % Assign app.ContextMain
            app.NLXEventFinderUIFigure.ContextMenu = app.ContextMain;
            app.Menu = uimenu(app.ContextMain);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMain);
            app.Menu2.Text = 'Menu2';
            app.ContextEditMessage = uicontextmenu(app.NLXEventFinderUIFigure);
            % Assign app.ContextEditMessage
            app.statusMess.ContextMenu = app.ContextEditMessage;
            app.Back2top = uimenu(app.ContextEditMessage);
            app.Back2top.MenuSelectedFcn = createCallbackFcn(app, @Back2topMenuSelected, true);
            app.Back2top.Text = 'Back to top';
            app.Clear = uimenu(app.ContextEditMessage);
            app.Clear.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.Clear.Text = 'Clear all';
            % Show the figure after all components are created
            app.NLXEventFinderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLX_Event_Finder_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NLXEventFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NLXEventFinderUIFigure)
        end
    end
end