classdef NLX_Event_Finder_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NLXEventFinderUIFigure  matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        SessCreate              matlab.ui.control.Button
        foldSel                 matlab.ui.control.Button
        RecordSel               matlab.ui.control.Spinner
        RecordNumSpinnerLabel   matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        PlotEvents              matlab.ui.control.Button
        EventDisplayPanel       matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        UIAxes                  matlab.ui.control.UIAxes
        startTfield             matlab.ui.control.EditField
        dateField               matlab.ui.control.EditField
        StartTimeLabel          matlab.ui.control.Label
        DateLabel               matlab.ui.control.Label
        statusMess              matlab.ui.control.TextArea
        StatusUpdateLabel       matlab.ui.control.Label
        EventList               matlab.ui.control.ListBox
        EventListListBoxLabel   matlab.ui.control.Label
        ContextMain             matlab.ui.container.ContextMenu
        Menu                    matlab.ui.container.Menu
        Menu2                   matlab.ui.container.Menu
        ContextEditMessage      matlab.ui.container.ContextMenu
        Back2top                matlab.ui.container.Menu
        Clear                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        eventLoc % Location for event files
        nevLIST
        timestamps
        ttl
        header
        evText
        EventDat
        maxRec
    end
    
    methods (Access = private)
        
        function updateMessage(app , newMessage)
            curTime = datestr(now);
            app.statusMess.Value = [app.statusMess.Value ;...
                ' ' , curTime , ' ' , newMessage];
            
        end
        
        function processEventFile(app)
            
            % 1. Determine number of Start Recording fields
            startFields = contains(app.EventDat.tInfo,'Starting Recording');
            numRecords = sum(startFields);
            
            % 2. Determine number of TTL flags
            startRecInds = find(startFields);
            numEventFlags = zeros(size(startRecInds));
            timeDUr = zeros(size(startRecInds));
            for ttlIbtw = 1:numRecords
                if ttlIbtw == numRecords % This is the last Start Recording file
                    numEventFlags(ttlIbtw) = numel(app.EventDat.tInfo(startRecInds(ttlIbtw)+1:end));
                    timeDUr(ttlIbtw) = (app.EventDat.ts(end) - app.EventDat.ts(startRecInds(ttlIbtw)))/1000000;
                else
                    numEventFlags(ttlIbtw) = (startRecInds(ttlIbtw + 1) - startRecInds(ttlIbtw)) - 1;
                    timeDUr(ttlIbtw) = (app.EventDat.ts(startRecInds(ttlIbtw + 1)) - app.EventDat.ts(startRecInds(ttlIbtw)))/1000000;
                end
            end
            
            % Clean up with respect to duration value - if negative remove
            startRecIuse = startRecInds(timeDUr > 0);
            eventsCountuse = numEventFlags(timeDUr > 0);
            timeDurUse = timeDUr(timeDUr > 0);
            timedurMins = round(timeDurUse/60,1);
            RecNum = transpose(1:numel(startRecIuse));
            app.maxRec = max(RecNum);
            
            % Populate table
            varNames = {'Records','Index','NumEvents','DurSeconds','DurMins'};
            app.UITable.Data = array2table([RecNum , startRecIuse , eventsCountuse, round(timeDurUse),timedurMins],...
                'VariableNames',varNames);

            app.UITable.ColumnName = varNames;
%             tdata = table(cnames,w,'VariableNames',{'Color','Wavelength'});
%             uit = uitable(fig,'Data',tdata,'ColumnEditable',true);
% 
%             app.startTfield.Value
%             app.EventDat
%             test = 1;
             app.RecordSel.Limits = [1 max(RecNum) + 1];

        end
        
        function closeFunction(app)
            selection = uiconfirm(app.NLXEventFinderUIFigure,'Close the figure window?',...
                'Confirmation');

            switch selection
                case 'OK'
                    delete(app.NLXEventFinderUIFigure)

                case 'Cancel'
                    return
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.statusMess.Value = 'Status UPDATES:';
        end

        % Callback function
        function foldSelClicked(app, event)
            
            nevCheck = 1;
            while nevCheck
                [app.eventLoc] = uigetdir('title','Select folder with Events file');
                
                cd(app.eventLoc)
                
                nevDIRa = dir("*.nev");
                nevDIRb = {nevDIRa.name};
                
                if isempty(nevDIRb)
                    continue
                else
                    nevCheck = 0;
                end
            end
            
            app.nevLIST = nevDIRb;
            app.EventList.Items = ['...',app.nevLIST];
            app.EventList.Enable = 'on';
            
        end

        % Value changed function: EventList
        function EventListValueChanged(app, event)
            curEvent = app.EventList.Value;
            
            % Update message
            updateMessage(app , ['LOAD: ' , curEvent])
            
            % Check which Event file the user clicked on
            if strcmp(curEvent,'...')
                % If the first value than return
                return
            else
                % If an available event file, then load using NLX reader
                [Timestamps, ~, TTLs, ~, evMessage, evHeader] =...
                    Nlx2MatEV(curEvent, [1 1 1 1 1], 1, 1, [] );
                
                % Extract timestamps, TTLs, and header
                app.timestamps = Timestamps;
                app.ttl = TTLs;
                app.header = evHeader;
                app.evText = evMessage;
                
                app.EventDat.ts = app.timestamps;
                app.EventDat.ttl = app.ttl;
                app.EventDat.header = app.header;
                app.EventDat.tInfo = app.evText;
                
            end
            
            % Extract start time and date
            tmpDate = app.header(contains(app.header,'TimeCreated'));
            dateParts = strsplit(tmpDate{1},' ');
            
            app.dateField.Value = dateParts{2};
            app.startTfield.Value = dateParts{3};
            
            % Update message with load and availability of TTLs
            updateMessage(app , ['Date: ' , app.dateField.Value])
            updateMessage(app , ['Start Time: ' , app.startTfield.Value])
            
            if ((length(app.timestamps) == 1) && app.timestamps == 0) ||...
                ~(any(contains(app.evText, {'Starting Recording','ttl'})))
                uialert(app.NLXEventFinderUIFigure, 'NO Timestamps!',...
                    "Timestamp Acquisition", "Icon","warning")
                updateMessage(app , 'NO Timestamps!')
            else
                % Count number of timestamps
                numberTSs = num2str(length(app.timestamps));
                % Update message
                updateMessage(app , ['Number of timestamps: ', numberTSs])
                % Activate Plot button
                updateMessage(app , 'Plot Button Activated')
                app.PlotEvents.Enable = 'on';
                
                processEventFile(app)
                app.SessCreate.Enable = 'on';
                
            end
            
        end

        % Menu selected function: Back2top
        function Back2topMenuSelected(app, event)
            scroll(app.statusMess, 'top')
        end

        % Menu selected function: Clear
        function ClearMenuSelected(app, event)
            app.statusMess.Value = 'Status UPDATES:';
        end

        % Button pushed function: PlotEvents
        function PlotEventsButtonPushed(app, event)
            
            reCSel = app.RecordSel.Value;
            
            recDat = app.UITable.Data(reCSel,:);
            
            if recDat.NumEvents < 2
                uialert(app.NLXEventFinderUIFigure, 'ONLY 1 EVENT!',...
                    "Visualizing EVENTs", "Icon","warning")
            else
                test = 1;
            end


        end

        % Value changed function: RecordSel
        function RecordSelValueChanged(app, event)
            value = app.RecordSel.Value;
            
            if value > app.maxRec
                app.RecordSel.Value = app.maxRec;
                return
            end
            
        end

        % Button pushed function: foldSel
        function foldSelButtonPushed(app, event)
            nevCheck = 1;
            while nevCheck
                [app.eventLoc] = uigetdir('title','Select folder with Events file');

                cd(app.eventLoc)

                nevDIRa = dir("*.nev");
                nevDIRb = {nevDIRa.name};

                if isempty(nevDIRb)
                    continue
                else
                    nevCheck = 0;
                end
            end

            app.nevLIST = nevDIRb;
            app.EventList.Items = ['...',app.nevLIST];
            app.EventList.Enable = 'on';
        end

        % Button pushed function: SessCreate
        function SessCreateButtonPushed(app, event)

            sessSel = uiconfirm(app.NLXEventFinderUIFigure, 'SESSION TYPE?',...
                      'Select Session Type','Options',...
                      {'Resting State','Behavior','Cancel'});
            
            switch sessSel
                case 'Cancel'
                    return
                case {'Resting State','Behavior'}

                    canCheck = 1;
                    while canCheck

                        prompt = {'Number of Sessions:'};
                        dlgtitle = 'Session #';
                        dims = [1 35];
                        definput = {'1'};
                        answer = inputdlg(prompt,dlgtitle,dims,definput);

                        if isempty(answer)
                            canSel = uiconfirm(app.NLXEventFinderUIFigure, 'CANCEL?',...
                                'Are you sure you want to Cancel','Options',...
                                {'No','Cancel'});
                            switch canSel
                                case 'No'
                                    continue
                                case 'Cancel'
                                    return
                            end
                        else
                            sessNUM = round(str2double(answer));
                            canCheck = 0;
                        end
                    end
                    
                    % Loop through sessions
                    for si = 1:length(sessNUM)
                        test = 1;  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%% IAM HERE
                    end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NLXEventFinderUIFigure = uifigure('Visible', 'off');
            app.NLXEventFinderUIFigure.Name = 'NLX Event Finder';
            app.NLXEventFinderUIFigure.Icon = 'icon.png';
            app.EventListListBoxLabel = uilabel(app.NLXEventFinderUIFigure);
            app.EventListListBoxLabel.Text = 'Event List';
            app.EventList = uilistbox(app.NLXEventFinderUIFigure);
            app.EventList.Items = {'...'};
            app.EventList.ValueChangedFcn = createCallbackFcn(app, @EventListValueChanged, true);
            app.EventList.Enable = 'off';
            app.EventList.Value = '...';
            app.StatusUpdateLabel = uilabel(app.NLXEventFinderUIFigure);
            app.StatusUpdateLabel.Text = {'Status '; 'Update'};
            app.statusMess = uitextarea(app.NLXEventFinderUIFigure);
            app.statusMess.Editable = 'off';
            app.DateLabel = uilabel(app.NLXEventFinderUIFigure);
            app.DateLabel.Text = 'Date:';
            app.StartTimeLabel = uilabel(app.NLXEventFinderUIFigure);
            app.StartTimeLabel.Text = 'Start Time:';
            app.dateField = uieditfield(app.NLXEventFinderUIFigure, 'text');
            app.startTfield = uieditfield(app.NLXEventFinderUIFigure, 'text');
            app.EventDisplayPanel = uipanel(app.NLXEventFinderUIFigure);
            app.EventDisplayPanel.Title = 'Event Display';
            app.GridLayout = uigridlayout(app.EventDisplayPanel);
            app.UIAxes = uiaxes(app.GridLayout);
            app.PlotEvents = uibutton(app.NLXEventFinderUIFigure, 'push');
            app.PlotEvents.ButtonPushedFcn = createCallbackFcn(app, @PlotEventsButtonPushed, true);
            app.PlotEvents.Enable = 'off';
            app.PlotEvents.Text = 'Plot Events';
            app.UITable = uitable(app.NLXEventFinderUIFigure);
            app.UITable.ColumnName = {'Records'; 'Time Start'; 'Duration'; 'Number of TTLs'};
            app.UITable.RowName = {};
            app.RecordNumSpinnerLabel = uilabel(app.NLXEventFinderUIFigure);
            app.RecordNumSpinnerLabel.Text = 'Record Num.';
            app.RecordSel = uispinner(app.NLXEventFinderUIFigure);
            app.RecordSel.Limits = [1 Inf];
            app.RecordSel.ValueChangedFcn = createCallbackFcn(app, @RecordSelValueChanged, true);
            app.RecordSel.Value = 1;
            app.Panel = uipanel(app.NLXEventFinderUIFigure);
            app.foldSel = uibutton(app.Panel, 'push');
            app.foldSel.ButtonPushedFcn = createCallbackFcn(app, @foldSelButtonPushed, true);
            app.foldSel.Icon = 'FolderOpenIconF.png';
            app.foldSel.Text = '';
            app.SessCreate = uibutton(app.Panel, 'push');
            app.SessCreate.ButtonPushedFcn = createCallbackFcn(app, @SessCreateButtonPushed, true);
            app.SessCreate.Icon = 'createSession.png';
            app.SessCreate.Enable = 'off';
            app.SessCreate.Text = '';
            app.ContextMain = uicontextmenu(app.NLXEventFinderUIFigure);
            app.Menu = uimenu(app.ContextMain);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMain);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMain
            app.NLXEventFinderUIFigure.ContextMenu = app.ContextMain;
            app.ContextEditMessage = uicontextmenu(app.NLXEventFinderUIFigure);
            app.Back2top = uimenu(app.ContextEditMessage);
            app.Back2top.MenuSelectedFcn = createCallbackFcn(app, @Back2topMenuSelected, true);
            app.Back2top.Text = 'Back to top';
            app.Clear = uimenu(app.ContextEditMessage);
            app.Clear.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.Clear.Text = 'Clear all';
            % Assign app.ContextEditMessage
            app.statusMess.ContextMenu = app.ContextEditMessage;
            % Show the figure after all components are created
            app.NLXEventFinderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLX_Event_Finder_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NLXEventFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NLXEventFinderUIFigure)
        end
    end
end