classdef NLX_Session2Neuro_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        compFile               matlab.ui.control.Table
        SPpanel                matlab.ui.container.Panel
        step1                  matlab.ui.control.Label
        chkNWB                 matlab.ui.control.Button
        expNWB                 matlab.ui.control.Button
        importSEEG             matlab.ui.control.Button
        step3                  matlab.ui.control.Label
        step2                  matlab.ui.control.Label
        UITable                matlab.ui.control.Table
        DLpanel                matlab.ui.container.Panel
        SETnwb                 matlab.ui.control.Button
        nwbLoc                 matlab.ui.control.EditField
        NWBLabel               matlab.ui.control.Label
        resetSE                matlab.ui.control.Button
        resetEV                matlab.ui.control.Button
        confirm                matlab.ui.control.Button
        sessLoc                matlab.ui.control.EditField
        SESSIONEditFieldLabel  matlab.ui.control.Label
        eventLoc               matlab.ui.control.EditField
        EVENTSEditFieldLabel   matlab.ui.control.Label
        FOpanel                matlab.ui.container.Panel
        fileLast               matlab.ui.control.Button
        fileNext               matlab.ui.control.Button
        fileLoad               matlab.ui.control.Button
    end

    
    %%%%%%%%% TO DO
    %%     f. Loop through sessions and export to NWB
    %%        i. User interface for variables as needed
    %%        ii. Skip behavior for now
    %%     g. Save in NWB directory
    %%     h. Save nwb directory to sessionInfo 
    
    
    properties (Access = private)
        mainLoc % Description
        sessFILE % Description
        eventLOC
        sessLOC
        nwbLOC
        sessLOGdat
        sessINFO
        curROW
        channelID
    end
    
    methods (Access = private)
        
        function foldReset(app , opt)
            
            switch opt
                case 'EV'
                    mEssage = "RESET RAW Event Folder";
                    tiTLE = "RAW Event Location";
                    uiMESSage = "Select folder with Events file";

                case 'SE' 
                    mEssage = "RESET Session Folder";
                    tiTLE = "Session Location";
                    uiMESSage = 'Select folder with Session file';
                    
                case 'NWB'
                    mEssage = "SET NWB Folder";
                    tiTLE = "NWB Save Location";
                    uiMESSage = 'Select folder to save NWB';

            end
            foldSEL = uiconfirm(app.UIFigure, mEssage,...
                tiTLE, "Icon","info");

            switch foldSEL
                case 'OK'
                    nevCheck = 1;
                    while nevCheck
                        [outLOC] = uigetdir('title',uiMESSage);

                        if outLOC == 0
                            ckCan = uiconfirm(app.UIFigure, 'DID you mean to CANCEL?',...
                                "NO Selection?", "Icon","info");

                            switch ckCan
                                case 'OK'
                                    return
                                case 'Cancel'
                                    continue
                            end
                        else
                            nevCheck = 0;
                        end

                    end

                case 'Cancel'
                    return

            end
            
            switch opt
                case 'EV'
                    app.eventLOC = outLOC;
                    app.eventLoc.Value = app.eventLOC;
                case 'SE'
                    app.sessLOC = outLOC;
                    app.sessLoc.Value = app.sessLOC;
                case 'NWB'
                    app.nwbLOC = outLOC;
                    app.nwbLoc.Value = app.nwbLOC;
                    
            end

        end
        
        function [foundCSC , timeDIF] = findCSCid(app , timeSTAMP)
            
            % Go to Event Directory
            cd(app.eventLOC)
            % Locate unique CSC extension files (i.e, CSC1__001, __002)
            cscRepoT = dir('*.ncs');
            cscRepo = {cscRepoT.name};
            
            fileEls = cellfun(@(x) strsplit(x, {'_','.'}), cscRepo, 'UniformOutput',false);
            
            cscAllnum = cellfun(@(x) x{1}, fileEls , 'UniformOutput',false);
            
            uniCSC = unique(cscAllnum);
            useCSC = uniCSC(1);
            
            allCSCns = transpose(cscRepo(contains(cscRepo,useCSC)));

            % Loop through all extension
            micAll = zeros(size(allCSCns));
            ephInd = zeros(size(allCSCns));
            for ci = 1:length(allCSCns)

                [TimestampsCSC, ~, ~, ~,...
                    ~, ~] = Nlx2MatCSC(allCSCns{ci}, [1 1 1 1 1], 1, 1, [] );

                % Create interpolation
                TimestampsINT = nlxEVinterp(app , TimestampsCSC);

                % Create a table with the time difference between nearest value
                % and timestamp
                offSETtime = abs(TimestampsINT - timeSTAMP);
                [micOffset , ephysInd] = min(min(offSETtime));
                
                micAll(ci) = micOffset;
                ephInd(ci) = ephysInd;

            end
            % Output CSC duplicate with the lowest value
            chckTable = table(allCSCns,micAll,ephInd);
            chckTable.msAll = chckTable.micAll/(1e+6);
            
            [~ , minREC] = min(chckTable.micAll);
            
            foundCSC = chckTable.allCSCns{minREC};
            timeDIF = chckTable.msAll(minREC);

        end
        
        function timeMAT = nlxEVinterp(~ , timeVEC)
            
            timeMAT = zeros(512,size(timeVEC,2));
            timeMAT(1,:) = timeVEC;
            for ti = 1:size(timeVEC,2)

                if ti == size(timeVEC,2)
                    tmpl = linspace(timeVEC(ti),timeVEC(ti)+16000,511);
                else
                    tmpl = linspace(timeVEC(ti),timeVEC(ti + 1),511);
                end
                tmplt = transpose(tmpl);
                timeMAT(2:512,ti) = tmplt;
            end


        end
        
        function highlightROW(app)
            
            allROWS = 1:height(app.UITable.Data);
            allCOLS = width(app.UITable.Data);
            
            hlRow = uistyle('BackgroundColor',[1 0.6 0.6]);
            nlRow = uistyle('BackgroundColor',[0.98 0.98 0.98]);
            
            rowSh = repmat(app.curROW,allCOLS,1);
            colSh = transpose(1:allCOLS);
            
            tallROWS = allROWS(allROWS ~= app.curROW);
            
            rowSn = reshape(repmat(tallROWS,allCOLS,1),[],1);
            colSn = transpose(repmat(1:allCOLS,1,numel(tallROWS)));
            
            addStyle(app.UITable,hlRow,'cell',[rowSh,colSh]);
            
            addStyle(app.UITable,nlRow,'cell',[rowSn,colSn]);
            
            scroll(app.UITable,'row',app.curROW)
            
        end
        
        function uiOUT = confirmMessage(app , stage)

            switch stage
                case 1
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE SESSION mat file",...
                        "allSessionData.mat", "Icon","info");
                case 2
                    uiOUT = uiconfirm(app.UIFigure, "LOCATED NWB",...
                        "NWB INSTALLED", "Icon","info");
                case 3
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE MICRO CSV",...
                        "MICRO CSV", "Icon","info");
                case 4
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE MACRO CSV",...
                        "MACRO CSV", "Icon","info");
                case 5
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE NWB Folder",...
                        "NWB location", "Icon","info");
                case 6
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE Matlab Folder",...
                        "MATLAB location", "Icon","info");
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: fileLoad
        function fileLoadButtonPushed(app, event)

            conOUT = confirmMessage(app,1);

            switch conOUT
                case "Cancel"
                    return
                case "OK"
                    [app.sessFILE , app.mainLoc] = uigetfile('*.mat');
                    cd(app.mainLoc)

                    load(app.sessFILE,"sessionINFo" , "saveSessAll")

                    app.sessLoc.Value = sessionINFo.sessiLOC;
                    app.eventLoc.Value = sessionINFo.eventLOC;
                    app.eventLOC = app.eventLoc.Value;
                    app.sessLOC = app.sessLoc.Value;
                    
                    app.sessLOGdat = saveSessAll;
                    app.sessINFO = sessionINFo;

                    app.resetEV.Enable = 'on';
                    app.resetSE.Enable = 'on';
                    app.SETnwb.Enable  = 'on';
                    app.confirm.Enable = 'on';
            end


        end

        % Button pushed function: confirm
        function confirmButtonPushed(app, event)
            
            % Save new information:
            load(app.sessFILE,"sessionINFo")
            sessionINFo.nwbLOC = app.nwbLOC;
            sessionINFo.eventLOC = app.eventLOC;
            sessionINFo.sessiLOC = app.sessLOC;
            
            app.sessINFO = sessionINFo;
            
            save(app.sessFILE,"sessionINFo" ,'-append')
            
            app.sessLoc.Editable = 'off';
            app.eventLoc.Editable = 'off';
            app.confirm.Enable = 'off';
            app.resetEV.Enable = 'off';
            app.resetSE.Enable = 'off';
            app.SETnwb.Enable  = 'off';
            
            % Load in first panel
            
            % Activate Session Panel
            app.SPpanel.Enable = 'on';
            
            % UI WAIT
            prog = uiprogressdlg(app.UIFigure, "Message","Setting up Table",...
                'Indeterminate','on');
            
            % Populate Session Table
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% IAM HERE
            sessID = cell(size(app.sessLOGdat));
            dateREC = cell(size(app.sessLOGdat));
            timeREC = cell(size(app.sessLOGdat));
            startTime = zeros(size(app.sessLOGdat));
            startInd = zeros(size(app.sessLOGdat));
            cscID = cell(size(app.sessLOGdat));
            durMS = zeros(size(app.sessLOGdat));
            
            for ti = 1:length(app.sessLOGdat)
                % Row 1: SessionID = SessType
                % Row 2: Date recorded = DateRec
                % Row 3: Time recorded = DateRec
                % Row 4: StartIndex = StartIndex
                % Row 5: StartTime = SessionInfo.ts(StartIndex)
                % Row 6: CSC ID = ?
                % Row 7: Duration offset in milliseconds
                sessID{ti} = app.sessLOGdat{ti}.SessType;
                dateREC{ti} = datestr(app.sessLOGdat{ti}.DateRec,'mm/dd/yyyy');
                timeREC{ti} = datestr(app.sessLOGdat{ti}.DateRec,"HH:MM:SS");
                startInd(ti) = app.sessLOGdat{ti}.StartIndex;
                startTime(ti) = app.sessLOGdat{ti}.SessionInfo.ts(startInd(ti));
                
                % Identify CSC file
                prog.Message = ['Working on file ', num2str(ti), ' of file ',...
                   num2str(length(app.sessLOGdat)) ];
                
                [cscNUM , offSet] = findCSCid(app , startTime(ti));
                
                cscID{ti} = cscNUM;
                durMS(ti) = offSet;

            end
            
            close(prog)
            
            datTable = table(sessID, dateREC, timeREC, startTime , startInd,...
                cscID, durMS,'VariableNames',app.UITable.ColumnName);
            
            app.UITable.Data = datTable;
            
            % Open up Neuro Processes
            %% Check if data are present in .mat file
            cd(app.sessLOC)
            exampleObject = matfile(app.sessFILE);
            
            % MACRO AND MICRO
            varlist = who(exampleObject);
            seFcheck = contains(varlist, 'channID');
            
            % If present - load it in but leave button off
            if any(seFcheck)
                 load(app.sessFILE,"channID")
                 app.channelID = channID;
            else % If not present - have user navigate and load
                app.importSEEG.Enable = 'on';
            end

            % Open up Next file
            app.fileNext.Enable = 'on';
            app.fileLast.Enable = 'on';
            
            % Turn on NWB
            app.chkNWB.Enable = 'on';
            
            
            % Highlight current row
            app.curROW = 1;
            highlightROW(app);

        end

        % Button pushed function: resetSE
        function resetSEButtonPushed(app, event)
            foldReset(app , 'SE')
        end

        % Button pushed function: resetEV
        function resetEVButtonPushed(app, event)
            foldReset(app , 'EV')
        end

        % Button pushed function: fileNext
        function fileNextButtonPushed(app, event)
            tmpROW = app.curROW;
            app.curROW = app.curROW + 1;
            
            if app.curROW > length(app.sessLOGdat)
                app.curROW = tmpROW;
                return
            else
                highlightROW(app)
            end
            % CONSIDER SCROLL
            
        end

        % Button pushed function: fileLast
        function fileLastButtonPushed(app, event)
            tmpROW = app.curROW;
            app.curROW = app.curROW - 1;

            if app.curROW < 1
                app.curROW = tmpROW; 
                return
            else
                highlightROW(app)
            end
            % CONSIDER SCROLL
            
        end

        % Button pushed function: importSEEG
        function importSEEGButtonPushed(app, event)
            
            chanType = {'micro','macro'};
            confiM = [3,4];
            for ci = 1:2
                ct2use = chanType{ci};
                
                confirmMessage(app,confiM(ci))
                
                switch ct2use
                    case 'micro'
                        [miFname , miLoc] = uigetfile('*.csv');
                        microLoc = fullfile(miLoc , miFname);
                        microTable = readtable(microLoc);
                        
                        app.channelID.micro = microTable;
                    case 'macro'
                        [maFname , maLoc] = uigetfile('*.csv');
                        macroLoc = fullfile(maLoc , maFname);
                        macroTable = readtable(macroLoc);
                        
                        app.channelID.macro = macroTable;

                end
            end
            channID = app.channelID;
            cd(app.sessLOC)
            save(app.sessFILE,'-append','channID');
            
            % Activate NWB button
            app.chkNWB.Enable = 'on';
            % Deactivate SEEG label button
            app.importSEEG.Enable = 'off';


        end

        % Button pushed function: chkNWB
        function chkNWBButtonPushed(app, event)
            
            % Check if on path
            nwbCkt = which('nwbExport');
            % if yes provide message and move on
            if ~isempty(nwbCkt)
                confirmMessage(app, 2)
                app.expNWB.Enable = 'on';
                return
            else
            % if no - ask if installed
            request = uiconfirm(app.UIFigure, "IS NWB Installed??",...
                "NWB INSTALLATION",'Options',{'YES','NO'});
            
            switch request
                case 'YES'
                    % if yes - located folder
                    locFOLD = confirmMessage(app,5);
                    switch locFOLD
                        case{'OK','Cancel'}
                            nwbFOLD = uigetdir();
                            addpath(genpath(nwbFOLD));
                    end

                case 'NO'
                    matLOC = confirmMessage(app,6);
                    switch matLOC
                        case{'OK','Cancel'}
                            matFOLD = uigetdir();
                            cd(matFOLD);
                            prog = uiprogressdlg(app.UIFigure, "Message","Installing NWB",...
                                'Indeterminate','on');
                            !git clone https://github.com/NeurodataWithoutBorders/matnwb.git

                            nwbdirF1 = dir();
                            nwbdirF2 = {nwbdirF1.name};
                            nwbdirF3 = nwbdirF2(~ismember(nwbdirF2, {'.','..'}));
                            
                            nwbdirF4 = nwbdirF3{contains(nwbdirF3,'matnwb')};
                            nwbdirF5 = [matFOLD , filesep , nwbdirF4];
                            
                            addpath(genpath(nwbdirF5))
                            
                            close(prog)
                            
                    end
            end

            end
            app.chkNWB.Enable = 'off';
            app.expNWB.Enable = 'on';


        end

        % Button pushed function: expNWB
        function expNWBButtonPushed(app, event)
            
            % USE 
            % https://www.mathworks.com/matlabcentral/fileexchange/67741-neurodatawithoutborders-matnwb
            test = 1;
            % Get current row
            curSess = app.sessLOGdat{app.curROW};
            % Check if it is behavior or resting state
            
            % Run NWB export and save in NWB folder
            
            for i = 1:3

                switch i
                    case 1 % NWB
                        prompt1 = {'session_description:','general_experimenter:',...
                            'general_session_id'};
                        dlgtitle1 = 'NWB Inputs';
                        dims = [1 35];
                        definput1 = {'Recognition Session','M. Darwin'};
                        answer1 = inputdlg(prompt1,dlgtitle1,dims,definput1);

                        % From data
                        identifier = app.sessINFO.SUBid{1};
                        timeStamP = curSess.SessionInfo.ts(curSess.StartIndex);
                        sessionstarttime = datetime(timeStamP/1000000,'ConvertFrom',...
                            'posixtime','TimeZone','America/Denver');

                        nwb = NwbFile( ...
                            'session_description', answer1{1},...
                            'identifier', identifier, ...
                            'session_start_time', sessionstarttime, ...
                            'general_experimenter', answer1{2}, ... % optional
                            'general_institution', 'UC Anschutz');
                        
                    case 2 % SUBJECT INFO
                        prompt2 = {'age:','sex:'};
                        dlgtitle2 = 'SUBJECT Inputs';
                        dims = [1 35];
                        definput2 = {'72','M'};
                        answer2 = inputdlg(prompt2,dlgtitle2,dims,definput2);
                        
                        identifier = app.sessINFO.SUBid{1};
                        
                        subject = types.core.Subject(...
                            'subject_id', identifier, ...
                            'age',answer2{1}, ...
                            'sex',answer2{2});
                        
                        nwb.general_subject = subject;
                        
                    case 3 % Behavior INFO
                        prompt3 = {'age:','sex:'};
                        dlgtitle3 = ' Inputs';
                        dims = [1 35];
                        definput3 = {'',''};
                        answer3 = inputdlg(prompt3,dlgtitle3,dims,definput3);


                end
            end
            
        end

        % Button pushed function: SETnwb
        function SETnwbButtonPushed(app, event)
            
            foldReset(app , 'NWB')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FOpanel = uipanel(app.UIFigure);
            app.FOpanel.Title = 'FILE OPTIONS';
            app.fileLoad = uibutton(app.FOpanel, 'push');
            app.fileLoad.ButtonPushedFcn = createCallbackFcn(app, @fileLoadButtonPushed, true);
            app.fileLoad.Text = {'Load Files'; ''};
            app.fileNext = uibutton(app.FOpanel, 'push');
            app.fileNext.ButtonPushedFcn = createCallbackFcn(app, @fileNextButtonPushed, true);
            app.fileNext.Enable = 'off';
            app.fileNext.Text = 'Next File';
            app.fileLast = uibutton(app.FOpanel, 'push');
            app.fileLast.ButtonPushedFcn = createCallbackFcn(app, @fileLastButtonPushed, true);
            app.fileLast.Enable = 'off';
            app.fileLast.Text = 'Last File';
            app.DLpanel = uipanel(app.UIFigure);
            app.DLpanel.Title = 'DIRECTORY LOCATIONS';
            app.EVENTSEditFieldLabel = uilabel(app.DLpanel);
            app.EVENTSEditFieldLabel.Text = 'EVENTS';
            app.eventLoc = uieditfield(app.DLpanel, 'text');
            app.SESSIONEditFieldLabel = uilabel(app.DLpanel);
            app.SESSIONEditFieldLabel.Text = 'SESSION';
            app.sessLoc = uieditfield(app.DLpanel, 'text');
            app.confirm = uibutton(app.DLpanel, 'push');
            app.confirm.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed, true);
            app.confirm.Enable = 'off';
            app.confirm.Text = 'CONFIRM';
            app.resetEV = uibutton(app.DLpanel, 'push');
            app.resetEV.ButtonPushedFcn = createCallbackFcn(app, @resetEVButtonPushed, true);
            app.resetEV.Enable = 'off';
            app.resetEV.Text = 'Reset';
            app.resetSE = uibutton(app.DLpanel, 'push');
            app.resetSE.ButtonPushedFcn = createCallbackFcn(app, @resetSEButtonPushed, true);
            app.resetSE.Enable = 'off';
            app.resetSE.Text = 'Reset';
            app.NWBLabel = uilabel(app.DLpanel);
            app.NWBLabel.Text = 'NWB';
            app.nwbLoc = uieditfield(app.DLpanel, 'text');
            app.SETnwb = uibutton(app.DLpanel, 'push');
            app.SETnwb.ButtonPushedFcn = createCallbackFcn(app, @SETnwbButtonPushed, true);
            app.SETnwb.Enable = 'off';
            app.SETnwb.Text = 'SET';
            app.SPpanel = uipanel(app.UIFigure);
            app.SPpanel.Enable = 'off';
            app.SPpanel.Title = 'SESSION PROCESSING';
            app.UITable = uitable(app.SPpanel);
            app.UITable.ColumnName = {'SessID'; 'RecDate'; 'RecTime'; 'StartT'; 'StartI'; 'CSC ID'; 'DurMS'};
            app.UITable.RowName = {};
            app.step2 = uilabel(app.SPpanel);
            app.step2.Text = 'STEP 2:';
            app.step3 = uilabel(app.SPpanel);
            app.step3.Text = 'STEP 3:';
            app.importSEEG = uibutton(app.SPpanel, 'push');
            app.importSEEG.ButtonPushedFcn = createCallbackFcn(app, @importSEEGButtonPushed, true);
            app.importSEEG.Enable = 'off';
            app.importSEEG.Text = 'Import Neuro Labels';
            app.expNWB = uibutton(app.SPpanel, 'push');
            app.expNWB.ButtonPushedFcn = createCallbackFcn(app, @expNWBButtonPushed, true);
            app.expNWB.Enable = 'off';
            app.expNWB.Text = 'Export to NWB';
            app.chkNWB = uibutton(app.SPpanel, 'push');
            app.chkNWB.ButtonPushedFcn = createCallbackFcn(app, @chkNWBButtonPushed, true);
            app.chkNWB.Enable = 'off';
            app.chkNWB.Text = 'Check for NWB';
            app.step1 = uilabel(app.SPpanel);
            app.step1.Text = 'STEP 1:';
            app.compFile = uitable(app.UIFigure);
            app.compFile.ColumnName = {'Sess#'; 'DONE'};
            app.compFile.RowName = {};
            app.compFile.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLX_Session2Neuro_v1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end