classdef NLX_StageSelect_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StageIDsPanel           matlab.ui.container.Panel
        STAGE3Label             matlab.ui.control.Label
        NLXSessionNWBLabel      matlab.ui.control.Label
        STAGE2Label             matlab.ui.control.Label
        NLXSessionInspectLabel  matlab.ui.control.Label
        STAGE1Label             matlab.ui.control.Label
        NLXEventFindLabel       matlab.ui.control.Label
        MainMenuPanel           matlab.ui.container.Panel
        StageSelectPanel        matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        NSN                     matlab.ui.control.Button
        NSI                     matlab.ui.control.Button
        NEF                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        curBUTTON  % Description
    end
    
    methods (Access = private)
        
        function runFolderOpen(app)
            % Search for folder in one folder up
            
            % Use which to determine if current mlapp is on path
            location = which("-all","NLX_StageSelect_v1");
            
            if isempty(location)
                return
            else
                path2all = extractBefore(location,"NLX_Stage_Select");
            end
            
            cd(path2all{1})
            
            % Add to path and add to path NLX IO
            allFolds1 = dir();
            allFolds2 = {allFolds1.name};
            allFolds3 = allFolds2(~ismember(allFolds2,{'..','.'}) & [allFolds1.isdir]);
            
            switch app.curBUTTON
                case 'NLX Event Find'

                    curFold = 'STAGE1_NLX_EventFinder';
                    curAPP = 'NLX_Event_Finder_v7.mlapp';

                case 'NLX Sesion Inspect'

                    curFold = 'STAGE2_NLX_SessionInspect';
                    curAPP = 'NLX_Session_Inspect_v2.mlapp';

                case 'NLX Sesion NWB'

                    curFold = 'STAGE3_NLX_Session2NWB';
                    curAPP = 'NLX_Session2Neuro_v10.mlapp';

            end
            
            % Check loc
            if matches(curFold,allFolds3)
                appPATH = [path2all{1} , curFold];
                nlxIOpath = [appPATH , filesep , 'NLX_IO_Code'];
                addpath(appPATH)
                addpath(nlxIOpath)
            end
            
            % Fire up program selected
            cd(appPATH)
            eval(curAPP)
            
            % Close current program
            delete(app)
            
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NEF
        function NEFButtonPushed(app, event)

            % Change the curBUTTON
            app.curBUTTON = 'NLX Event Find';
            % Ask user if certain of program is correct
            selection = uiconfirm(app.UIFigure, ["Did you select ", app.curBUTTON],...
                "Check Selection","Icon","info");
            
            if selection    
            % Search for folder in one folder up
            runFolderOpen(app)

            else
                return
            end
        end

        % Button pushed function: NSI
        function NSIButtonPushed(app, event)
            % Change the curBUTTON
            app.curBUTTON = 'NLX Sesion Inspect';
            % Ask user if certain of program is correct
            selection = uiconfirm(app.UIFigure, ["Did you select ", app.curBUTTON],...
                "Check Selection","Icon","info");

            if selection
                % Search for folder in one folder up
                runFolderOpen(app)

            else
                return
            end
        end

        % Button pushed function: NSN
        function NSNButtonPushed(app, event)
            % Change the curBUTTON
            app.curBUTTON = 'NLX Sesion NWB';
            % Ask user if certain of program is correct
            selection = uiconfirm(app.UIFigure, ["Did you select ", app.curBUTTON],...
                "Check Selection","Icon","info");

            if selection
                % Search for folder in one folder up
                runFolderOpen(app)

            else
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StageSelectPanel = uipanel(app.UIFigure);
            app.StageSelectPanel.Title = 'Stage Select';
            app.GridLayout = uigridlayout(app.StageSelectPanel);
            app.NEF = uibutton(app.GridLayout, 'push');
            app.NEF.ButtonPushedFcn = createCallbackFcn(app, @NEFButtonPushed, true);
            app.NEF.Icon = 'NEFicon.png';
            app.NEF.Text = '';
            app.NSI = uibutton(app.GridLayout, 'push');
            app.NSI.ButtonPushedFcn = createCallbackFcn(app, @NSIButtonPushed, true);
            app.NSI.Icon = 'NSIicon.png';
            app.NSI.Text = '';
            app.NSN = uibutton(app.GridLayout, 'push');
            app.NSN.ButtonPushedFcn = createCallbackFcn(app, @NSNButtonPushed, true);
            app.NSN.Icon = 'NSNicon.png';
            app.NSN.Text = '';
            app.MainMenuPanel = uipanel(app.UIFigure);
            app.MainMenuPanel.Title = 'Main Menu';
            app.StageIDsPanel = uipanel(app.UIFigure);
            app.StageIDsPanel.Title = 'Stage IDs';
            app.NLXEventFindLabel = uilabel(app.StageIDsPanel);
            app.NLXEventFindLabel.Text = {'NLX'; 'Event'; 'Find'};
            app.STAGE1Label = uilabel(app.StageIDsPanel);
            app.STAGE1Label.Text = 'STAGE 1';
            app.NLXSessionInspectLabel = uilabel(app.StageIDsPanel);
            app.NLXSessionInspectLabel.Text = {'NLX'; 'Session'; 'Inspect'; ''};
            app.STAGE2Label = uilabel(app.StageIDsPanel);
            app.STAGE2Label.Text = 'STAGE 2';
            app.NLXSessionNWBLabel = uilabel(app.StageIDsPanel);
            app.NLXSessionNWBLabel.Text = {'NLX'; 'Session'; 'NWB'; ''};
            app.STAGE3Label = uilabel(app.StageIDsPanel);
            app.STAGE3Label.Text = 'STAGE 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLX_StageSelect_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end