classdef NLX_SessionFix_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NLXEventFinderUIFigure  matlab.ui.Figure
        SessionInfoPanel        matlab.ui.container.Panel
        ACCEPTSwitch            matlab.ui.control.Switch
        ACCEPTSwitchLabel       matlab.ui.control.Label
        SessionTypeButtonGroup  matlab.ui.container.ButtonGroup
        RestingstateButton      matlab.ui.control.RadioButton
        BehaviorButton          matlab.ui.control.RadioButton
        StopIndexSpinner        matlab.ui.control.Spinner
        StopIndexSpinnerLabel   matlab.ui.control.Label
        StartIndexSpinner       matlab.ui.control.Spinner
        StartIndexSpinnerLabel  matlab.ui.control.Label
        SessionNumberSpinner    matlab.ui.control.Spinner
        SessionNumberLabel      matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        foldCREATE              matlab.ui.control.Button
        closeProg               matlab.ui.control.Button
        ExpSess                 matlab.ui.control.Button
        DBmode                  matlab.ui.control.Button
        SessTable               matlab.ui.control.Table
        SessList                matlab.ui.control.ListBox
        SessionListLabel        matlab.ui.control.Label
        ContextMain             matlab.ui.container.ContextMenu
        Menu                    matlab.ui.container.Menu
        Menu2                   matlab.ui.container.Menu
        ContextEditMessage      matlab.ui.container.ContextMenu
        Back2top                matlab.ui.container.Menu
        Clear                   matlab.ui.container.Menu
    end


    properties (Access = private)
        eventLoc % Location for event files
        nevLIST
        timestamps
        ttl
        header
        evText
        EventDat
        maxRec
        defaultDet
        SaveSession
        saveSessCount
        sessSaveLoc
        eventLogtab
        LogTasks
        useEvList
    end

    %%%% TO DO
    %% 4/2/2021
    % 1.Pressing Load button - loads in EventTracker File
    % 2.Loads in List of sessions
    % 3.A new list will be created by splitting sessions


    %%%%% STEPS
    % 1. Load folders - Raw and Save
    % 2. Search for Log file
    % 3. If not created
    % 4. Cycle through files and create
    % 5. Save and move to next step
    % 6. If Log file created
    % 7. Show available Event files in List


    methods (Access = private)


        function processEventFile(app)

            % 1. Determine number of Start Recording fields
            startFields = contains(app.EventDat.tInfo,'Starting Recording');
            numRecords = sum(startFields);

            % 2. Determine number of TTL flags
            startRecInds = find(startFields);
            numEventFlags = zeros(size(startRecInds));
            timeDUr = zeros(size(startRecInds));
            for ttlIbtw = 1:numRecords
                if ttlIbtw == numRecords % This is the last Start Recording file
                    numEventFlags(ttlIbtw) = numel(app.EventDat.tInfo(startRecInds(ttlIbtw)+1:end));
                    timeDUr(ttlIbtw) = (app.EventDat.ts(end) - app.EventDat.ts(startRecInds(ttlIbtw)))/1000000;
                else
                    numEventFlags(ttlIbtw) = (startRecInds(ttlIbtw + 1) - startRecInds(ttlIbtw)) - 1;
                    timeDUr(ttlIbtw) = (app.EventDat.ts(startRecInds(ttlIbtw + 1)) - app.EventDat.ts(startRecInds(ttlIbtw)))/1000000;
                end
            end

            % Clean up with respect to duration value - if negative remove
            startRecIuse = startRecInds(timeDUr > 0);
            eventsCountuse = numEventFlags(timeDUr > 0);
            timeDurUse = timeDUr(timeDUr > 0);
            timedurMins = round(timeDurUse/60,1);
            RecNum = transpose(1:numel(startRecIuse));
            app.maxRec = max(RecNum);

            % Populate table
            varNames = {'Records','Index','NumEvents','DurSeconds','DurMins'};
            app.SessTable.Data = array2table([RecNum , startRecIuse , eventsCountuse, round(timeDurUse),timedurMins],...
                'VariableNames',varNames);

            app.SessTable.ColumnName = varNames;

            app.RecordSel.Limits = [1 max(RecNum) + 1];

            % Preemptively update session stop index
            app.StopIndexSpinner.Value = numel(app.EventDat.ts);

        end

        function closeFunction(app)
            selection = uiconfirm(app.NLXEventFinderUIFigure,'Close PROGRAM?',...
                'Confirmation');

            switch selection
                case 'OK'
                    delete(app.NLXEventFinderUIFigure)

                case 'Cancel'
                    return
            end

        end

        function matdateStr = convertUNIXtime(~, uniXTimes) % '~' is placeholder for app

            matdateStr = NaT(size(uniXTimes),'TimeZone','America/Denver');
            for ci = 1:length(uniXTimes)
                timeREcord = datetime(uniXTimes(ci)/1000000,'ConvertFrom',...
                    'posixtime','TimeZone','America/Denver');
                matdateStr(ci) = timeREcord;
            end

        end

        function processLOGview(app , LOGind)

            curEvent = app.eventLogtab.EventFnames{LOGind};

            [Timestamps, ~, TTLs, ~, evMessage, evHeader] =...
                Nlx2MatEV(curEvent, [1 1 1 1 1], 1, 1, [] );

            % Extract timestamps, TTLs, and header
            app.timestamps = Timestamps;
            app.ttl = TTLs;
            app.header = evHeader;
            app.evText = evMessage;

            app.EventDat.ts = app.timestamps;
            app.EventDat.ttl = app.ttl;
            app.EventDat.header = app.header;
            app.EventDat.tInfo = app.evText;

            % ADD CHECK
            if ((length(app.timestamps) == 1) && app.timestamps == 0) ||...
                    ~(any(contains(app.evText, {'Starting Recording','ttl'})))

                % Auto advance and populate table
                % 1. Get current log row
                curLOGev = app.LogTasks.logInds(app.LogTasks.curNum);
                % 2. Get decision
                decision = 'NotUsable';
                viewFFile = false;
                logView = true;

                app.eventLogtab.ViewFile(curLOGev) = viewFFile;
                app.eventLogtab.ReasonNotV{curLOGev} = decision;
                app.eventLogtab.LOGviewed(curLOGev) = logView;
                app.SkipEv.Value = 1;
                app.ProcEv.Value = 0;

                % 3. Advance
                updateLOGVIEW(app)

                startRecIuse = [0;0;0];
                eventsCountuse = [0;0;0];
                timeDurUse = [0;0;0];
                timedurMins = [0;0;0];
                RecNum = [0;0;0];

                % Populate table
                varNames = {'Records','Index','NumEvents','DurSeconds','DurMins'};
                app.SessTable.Data = array2table([RecNum , startRecIuse , eventsCountuse, round(timeDurUse),timedurMins],...
                    'VariableNames',varNames);

            else

                updateLOGVIEW(app)

                processEventFile(app)

            end

        end

        function updateLOGVIEW(app)
            tmpDate1 = convertUNIXtime(app,app.timestamps(1));

            dayDate = datestr(tmpDate1,'mm/dd/yyyy');
            timeDate = datestr(tmpDate1,"HH:MM:SS");

            app.dateField.Value = dayDate;
            app.startTfield.Value = timeDate;

            % Update message with load and availability of TTLs
            updateMessage(app , ['Date: ' , app.dateField.Value])
            updateMessage(app , ['Start Time: ' , app.startTfield.Value])

            % Count number of timestamps
            numberTSs = num2str(length(app.timestamps));
            % Update message
            updateMessage(app , ['Number of timestamps: ', numberTSs])
        end

        function createLOGtable(app)
            EventFnames = transpose(app.nevLIST);
            ViewFile = ones(size(EventFnames),'logical');
            ReasonNotV = repmat({''},length(EventFnames),1);
            LOGviewed = zeros(size(EventFnames),'logical');
            NeuroEvents = repmat({''},length(EventFnames),1);
            DateRecord = NaT(size(EventFnames),'TimeZone','America/Denver');
            StartTime = NaT(size(EventFnames),'TimeZone','America/Denver');
            app.eventLogtab = table(EventFnames,ViewFile,ReasonNotV,LOGviewed,NeuroEvents,DateRecord,StartTime);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.defaultDet = 0;
            app.saveSessCount = 1;
            app.SaveSession = cell(100,1);
        end

        % Value changed function: SessList
        function SessListValueChanged(app, event)


            curEvent = app.SessList.Value;
            % Check which Event file the user clicked on
            if strcmp(curEvent,'...')
                % If the first value than return
                return
            else
                % If an available event file, then load using NLX reader
                [Timestamps, ~, TTLs, ~, evMessage, evHeader] =...
                    Nlx2MatEV(curEvent, [1 1 1 1 1], 1, 1, [] );

                % Extract timestamps, TTLs, and header
                app.timestamps = Timestamps;
                app.ttl = TTLs;
                app.header = evHeader;
                app.evText = evMessage;

                app.EventDat.ts = app.timestamps;
                app.EventDat.ttl = app.ttl;
                app.EventDat.header = app.header;
                app.EventDat.tInfo = app.evText;

            end

            app.ACCEPTSwitch.Enable = 'on';
            app.ACCEPTSwitch.Value = 'Off';
            app.SessionNumberSpinner.Enable = 'on';
            app.StartIndexSpinner.Enable = 'on';
            app.StopIndexSpinner.Enable = 'on';
            app.SessionInfoPanel.Enable = 'on';

            cla(app.UIAxes);
            % Update message
            updateMessage(app , ['LOAD: ' , curEvent])


            % Extract start time and date
            % Update Time / Date - use first time stamp
            tmpDate1 = convertUNIXtime(app,Timestamps(1));

            dayDate = datestr(tmpDate1,'mm/dd/yyyy');
            timeDate = datestr(tmpDate1,"HH:MM:SS");

            app.dateField.Value = dayDate;
            app.startTfield.Value = timeDate;

            % Find event in table
            eventIND = contains(app.eventLogtab.EventFnames, curEvent);
            app.eventLogtab.DateRecord(eventIND) = tmpDate1;

            % Update message with load and availability of TTLs
            updateMessage(app , ['Date: ' , app.dateField.Value])
            updateMessage(app , ['Start Time: ' , app.startTfield.Value])

            if ((length(app.timestamps) == 1) && app.timestamps == 0) ||...
                    ~(any(contains(app.evText, {'Starting Recording','ttl'})))
                uialert(app.NLXEventFinderUIFigure, 'NO Timestamps!',...
                    "Timestamp Acquisition", "Icon","warning")
                updateMessage(app , 'NO Timestamps!')
            else
                % Count number of timestamps
                numberTSs = num2str(length(app.timestamps));
                % Update message
                updateMessage(app , ['Number of timestamps: ', numberTSs])
                % Activate Plot button
                updateMessage(app , 'Plot Button Activated')
                app.PlotEvents.Enable = 'on';

                processEventFile(app)
                app.SessCreate.Enable = 'on';
                app.SessionInfoPanel.Enable = 'on';

            end

        end

        % Menu selected function: Back2top
        function Back2topMenuSelected(app, event)
            scroll(app.statusMess, 'top')
        end

        % Menu selected function: Clear
        function ClearMenuSelected(app, event)
            app.statusMess.Value = 'Status UPDATES:';
        end

        % Callback function
        function RecordSelValueChanged(app, event)
            value = app.RecordSel.Value;

            if value > app.maxRec
                app.RecordSel.Value = app.maxRec;
                return
            end

        end

        % Callback function
        function SessCreateButtonPushed(app, event)


            if strcmp(app.ACCEPTSwitch.Value,'On')
                % Loop through sessions
                sessNUM = app.SessionNumberSpinner.Value;

                sessSel = app.SessionTypeButtonGroup.SelectedObject.Text;

                tmpSession = struct;
                for si = 1:sessNUM
                    tmpStartI = app.StartIndexSpinner.Value;
                    tmpStopI = app.StopIndexSpinner.Value;
                    % Populate defaults

                    % Extract data
                    tmpSession.StartIndex(si) = tmpStartI;
                    tmpSession.StopIndex(si)  = tmpStopI;
                    tmpSession.StartTime(si)  = app.EventDat.ts(tmpStartI) - 2*1000000;
                    tmpSession.StopTime(si)   = app.EventDat.ts(tmpStopI) + 2*1000000;
                    tmpSession.StartStr{si}   = app.EventDat.tInfo{tmpStartI};
                    tmpSession.StopStr{si}    = app.EventDat.tInfo{tmpStopI};

                end

                % All other information
                tmpSession.SessionInfo = app.EventDat;
                tmpSession.SessType = sessSel;
                tmpSession.DateRec = convertUNIXtime(app, app.EventDat.ts(1));

                app.SaveSession{app.saveSessCount} = tmpSession;
                app.saveSessCount = app.saveSessCount + 1;

                % Update Table
                curEvent = app.SessList.Value;
                eventIND = contains(app.eventLogtab.EventFnames, curEvent);
                tmpDate1 = convertUNIXtime(app,app.timestamps(1));
                app.eventLogtab.DateRecord(eventIND) = tmpDate1;
                app.eventLogtab.ViewFile(eventIND) = false;
                app.eventLogtab.ReasonNotV{eventIND} = sessSel;

                % Save table
                cd(app.eventLoc)
                eventTtable = app.eventLogtab;
                save("EventLogTracker.mat","eventTtable")

                % Remove from Event List
                cureventLIST = app.SessList.Items;
                app.SessList.Items =  cureventLIST(~contains(cureventLIST,curEvent));

                app.ExpSess.Enable = 'on';

                % Re-enable Event List and Session Info and Clear Session
                % Info
                app.SessList.Enable = 'on';
                app.SessionInfoPanel.Enable = 'on';

                app.SessionNumberSpinner.Value = 1;
                app.StartIndexSpinner.Value = 1;
                app.StopIndexSpinner.Value = 1;
                app.ACCEPTSwitch.Value = 'Off';

                % Clear table values
                app.SessTable.Data = [];
                app.ACCEPTSwitch.Enable = 'off';

            elseif strcmp(app.ACCEPTSwitch.Value,'Off')
                return
            end


        end

        % Key press function: NLXEventFinderUIFigure
        function NLXEventFinderUIFigureKeyPress(app, event)
            key = event.Key;

            if strcmp(app.DBmode.Visible,'on')
                return
            else
                switch key
                    case 'p'
                        app.defaultDet = app.defaultDet + 1;

                        if app.defaultDet == 3
                            app.DBmode.Visible = 'on';
                            app.DBmode.Text = 'DEBUG MODE';
                        end
                    otherwise
                        return

                end
            end

        end

        % Button pushed function: DBmode
        function DBmodeButtonPushed(app, event)

            %directory
            app.eventLoc = 'D:\Dropbox\TestEventFiles\EVENTs';
            app.sessSaveLoc = 'D:\Dropbox\TestEventFiles\SesssionSAVEs';
            cd(app.eventLoc)

            nevDIRa = dir("*.nev");
            nevDIRb = {nevDIRa.name};

            app.nevLIST = nevDIRb;

            % Create Event log file and save
            createLOGtable(app)

            app.LOGVIEW.Enable = 'on';

            logIndices = find(~(app.eventLogtab.LOGviewed));

            app.LogTasks.curNum = 1;
            app.LogTasks.totalN = numel(logIndices);
            app.LogTasks.logInds = logIndices;
            processLOGview(app , app.LogTasks.logInds(1))

            app.curEvbutton.Title = ['CURRENT FILE: ' ,...
                app.eventLogtab.EventFnames{app.LogTasks.logInds(1)}];

        end

        % Button pushed function: closeProg
        function closeProgButtonPushed(app, event)
            closeFunction(app)
        end

        % Callback function
        function NextEVEnButtonPushed(app, event)

            % Update LogEvent Table

            % 1. Get current log row
            curLOGev = app.LogTasks.logInds(app.LogTasks.curNum);
            % 2. Get decision
            if app.SkipEv.Value
                decision = 'NotUsable';
                viewFFile = false;
                neuroE = 'NotUsable';
            elseif app.ProcEv.Value
                decision = '';
                viewFFile = true;
                neuroE = '';
            end
            logView = true;

            app.eventLogtab.ViewFile(curLOGev) = viewFFile;
            app.eventLogtab.ReasonNotV{curLOGev} = decision;
            app.eventLogtab.LOGviewed(curLOGev) = logView;
            app.eventLogtab.NeuroEvents{curLOGev} = neuroE;

            % Determine if this is the last file
            if curLOGev == app.LogTasks.totalN
                % UICONFIRM that Log viewing is done
                doneck = uiconfirm(app.NLXEventFinderUIFigure, 'LOG VIEWing Complete',...
                    'You are done checking LOG files','Options',...
                    {'OK','Cancel'});
                switch doneck
                    case {'OK','Cancel'}

                        % TURN off Log view
                        app.LOGVIEW.Enable = 'off';
                        % TURN event list - populate with relevant event files
                        app.SessList.Enable = 'on';
                        app.useEvList = app.nevLIST(app.eventLogtab.ViewFile);
                        % Clean up NEVLIST based on ViewFile table
                        app.SessList.Items = ['...' , app.useEvList];

                end

                % SAVE out Event file log
                cd(app.eventLoc)
                eventTtable = app.eventLogtab;
                save("EventLogTracker.mat","eventTtable")

            else
                % Advance number and Log view
                app.LogTasks.curNum = app.LogTasks.curNum + 1;
                processLOGview(app , app.LogTasks.logInds(app.LogTasks.curNum))
                app.curEvbutton.Title = ['CURRENT FILE: ' ,...
                    app.eventLogtab.EventFnames{app.LogTasks.logInds(app.LogTasks.curNum)}];

            end

        end

        % Button pushed function: ExpSess
        function ExpSessButtonPushed(app, event)

            % Check if this is the last EVENT
            if strcmp(app.SessList.Items,'...') && numel(app.SessList.Items) == 1

                cd(app.sessSaveLoc)
                % clean up app.SaveSession
                saveSessAll = app.SaveSession(cellfun(@(x) ~isempty(x),...
                    app.SaveSession, 'UniformOutput',true));

                % Session file info
                eventSaveLoc = app.eventLoc;
                sessionSaveLoc = app.sessSaveLoc;

                % Subject ID
                prompt = 'Subject ID';
                dlgtitle = 'SUBJECT';
                dims = [1 35];
                definput = {'SUBJECT_ID'};
                subID = inputdlg(prompt,dlgtitle,dims,definput);

                % Latest save
                tNOW = datestr(datetime('now'));
                tNOWrd = strrep(tNOW,'-','');
                tNOWfi = strrep(tNOWrd,' ','_');

                % Session INFO
                sessionINFo.eventLOC = eventSaveLoc;
                sessionINFo.sessiLOC = sessionSaveLoc;
                sessionINFo.SUBid = subID;
                sessionINFo.Created = tNOWfi;

                % Save data
                save("allSessionData.mat","saveSessAll","sessionINFo");

                % Save table
                cd(app.eventLoc)
                eventTtable = app.eventLogtab;
                save("EventLogTracker.mat","eventTtable");


            else
                return
            end

        end

        % Value changed function: ACCEPTSwitch
        function ACCEPTSwitchValueChanged(app, event)
            value = app.ACCEPTSwitch.Value;

            switch value
                case 'On'
                    app.SessionNumberSpinner.Enable = 'off';
                    app.StartIndexSpinner.Enable = 'off';
                    app.StopIndexSpinner.Enable = 'off';
                    app.SessionInfoPanel.Enable = 'off';

                    app.SessList.Enable = 'off';


                case 'Off'
                    app.SessionNumberSpinner.Enable = 'on';
                    app.StartIndexSpinner.Enable = 'on';
                    app.StopIndexSpinner.Enable = 'on';
                    app.SessionInfoPanel.Enable = 'on';


            end


        end

        % Button pushed function: foldCREATE
        function foldCREATEButtonPushed(app, event)

            % User Locates a subject folder .mat setFold file
            % UICONFIRM PROMPT TO SELECT RAW FOLDER
            uiconfirm(app.NLXEventFinderUIFigure, 'SELECT RAW Event Folder',...
                "RAW Event Location", "Icon","info");

            [app.locsFILE , app.mainLoc] = uigetfile('*.mat');

            if isempty(app.locsFILE) || ~matches('nwbCKfold.mat' , app.locsFILE)
                uialert(app.UIFigure,'File not found - Run NLX_Event_Finder GUI','Invalid File');
                return
            else

                cd(app.mainLoc)

                load(app.locsFILE , 'nwbFilesLOCS')

                app.sessLoc.Value = nwbFilesLOCS.sessSAVloc;
                app.eventLoc.Value = nwbFilesLOCS.eventLOC;
                app.nwbLoc.Value = nwbFilesLOCS.nwbSAVloc;
                app.eventLOC = app.eventLoc.Value;
                app.sessLOC = app.sessLoc.Value;
                app.nwbLOC = app.nwbLoc.Value ;

                % CD to session Folder and load session Files
                cd(app.sessLOC)

                matfindck = dir('*.mat');
                matFindckN = {matfindck.name};

                if isempty(matFindckN) || ~matches('allSessionData.mat',matFindckN)
                    uialert(app.UIFigure,'File not found - Run NLX_Event_Finder GUI','Invalid File');
                    return
                else
                    app.sessFILE = matFindckN{1};
                end

                load(app.sessFILE, "sessionINFo" , "saveSessAll")

                app.sessLOGdat = saveSessAll;
                app.sessINFO = sessionINFo;

                app.foldCREATE.Enable = 'off';
                app.ExpSess.Enable = 'on';

            end

            yrdy = 2;

            % Populate SESSION LIST


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NLXEventFinderUIFigure = uifigure('Visible', 'off');
            app.NLXEventFinderUIFigure.Name = 'NLX Event Finder';
            app.NLXEventFinderUIFigure.Icon = 'icon.png';
            app.NLXEventFinderUIFigure.KeyPressFcn = createCallbackFcn(app, @NLXEventFinderUIFigureKeyPress, true);
            app.SessionListLabel = uilabel(app.NLXEventFinderUIFigure);
            app.SessionListLabel.Text = 'Session List';
            app.SessList = uilistbox(app.NLXEventFinderUIFigure);
            app.SessList.Items = {'...'};
            app.SessList.ValueChangedFcn = createCallbackFcn(app, @SessListValueChanged, true);
            app.SessList.Enable = 'off';
            app.SessList.Value = '...';
            app.SessTable = uitable(app.NLXEventFinderUIFigure);
            app.SessTable.ColumnName = {'Session#'; 'Records'; 'Time Start'; 'Duration'; 'Number of TTLs'};
            app.SessTable.RowName = {};
            app.Panel = uipanel(app.NLXEventFinderUIFigure);
            app.DBmode = uibutton(app.Panel, 'push');
            app.DBmode.ButtonPushedFcn = createCallbackFcn(app, @DBmodeButtonPushed, true);
            app.DBmode.Visible = 'off';
            app.DBmode.Text = '';
            app.ExpSess = uibutton(app.Panel, 'push');
            app.ExpSess.ButtonPushedFcn = createCallbackFcn(app, @ExpSessButtonPushed, true);
            app.ExpSess.Icon = 'exportButton.png';
            app.ExpSess.Enable = 'off';
            app.ExpSess.Text = '';
            app.closeProg = uibutton(app.Panel, 'push');
            app.closeProg.ButtonPushedFcn = createCallbackFcn(app, @closeProgButtonPushed, true);
            app.closeProg.Icon = 'closeButton.png';
            app.closeProg.Text = '';
            app.foldCREATE = uibutton(app.Panel, 'push');
            app.foldCREATE.ButtonPushedFcn = createCallbackFcn(app, @foldCREATEButtonPushed, true);
            app.foldCREATE.Icon = 'FolderCREATE.png';
            app.foldCREATE.Text = '';
            app.SessionInfoPanel = uipanel(app.NLXEventFinderUIFigure);
            app.SessionInfoPanel.Enable = 'off';
            app.SessionInfoPanel.Title = 'Session Info';
            app.SessionNumberLabel = uilabel(app.SessionInfoPanel);
            app.SessionNumberLabel.Text = {'Session '; 'Number'};
            app.SessionNumberSpinner = uispinner(app.SessionInfoPanel);
            app.SessionNumberSpinner.Limits = [1 3];
            app.SessionNumberSpinner.Value = 1;
            app.StartIndexSpinnerLabel = uilabel(app.SessionInfoPanel);
            app.StartIndexSpinnerLabel.Text = 'Start Index';
            app.StartIndexSpinner = uispinner(app.SessionInfoPanel);
            app.StartIndexSpinner.Limits = [1 10000];
            app.StartIndexSpinner.Value = 1;
            app.StopIndexSpinnerLabel = uilabel(app.SessionInfoPanel);
            app.StopIndexSpinnerLabel.Text = 'Stop Index';
            app.StopIndexSpinner = uispinner(app.SessionInfoPanel);
            app.StopIndexSpinner.Limits = [1 10000];
            app.StopIndexSpinner.Value = 1;
            app.SessionTypeButtonGroup = uibuttongroup(app.SessionInfoPanel);
            app.SessionTypeButtonGroup.Title = 'Session Type';
            app.BehaviorButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.BehaviorButton.Text = 'Behavior';
            app.BehaviorButton.Value = true;
            app.RestingstateButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.RestingstateButton.Text = 'Resting state';
            app.ACCEPTSwitchLabel = uilabel(app.SessionInfoPanel);
            app.ACCEPTSwitchLabel.Text = 'ACCEPT';
            app.ACCEPTSwitch = uiswitch(app.SessionInfoPanel, 'slider');
            app.ACCEPTSwitch.ValueChangedFcn = createCallbackFcn(app, @ACCEPTSwitchValueChanged, true);
            app.ContextMain = uicontextmenu(app.NLXEventFinderUIFigure);
            app.Menu = uimenu(app.ContextMain);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMain);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMain
            app.NLXEventFinderUIFigure.ContextMenu = app.ContextMain;
            app.ContextEditMessage = uicontextmenu(app.NLXEventFinderUIFigure);
            app.Back2top = uimenu(app.ContextEditMessage);
            app.Back2top.MenuSelectedFcn = createCallbackFcn(app, @Back2topMenuSelected, true);
            app.Back2top.Text = 'Back to top';
            app.Clear = uimenu(app.ContextEditMessage);
            app.Clear.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.Clear.Text = 'Clear all';
            % Show the figure after all components are created
            app.NLXEventFinderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLX_SessionFix_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NLXEventFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NLXEventFinderUIFigure)
        end
    end
end