classdef nlxephys < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SpectrogramButton  matlab.ui.control.Button
        powrSpecButton     matlab.ui.control.Button
        FixxaxisButton     matlab.ui.control.Button
        FixyaxisButton     matlab.ui.control.Button
        ApplyADbitButton   matlab.ui.control.Button
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        macroDATl % Description
        nwbFile
        dat2plot
        mw1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Cd to matlab folder 
            mainDDir = 'C:\Users\John\Documents\MATLAB';
            % Add NWB
            nwbDDir = [mainDDir , filesep , 'matnwb'];
            addpath(nwbDDir);
            % Add NLX
            nlxDDir = [mainDDir , filesep , 'MatlabImportExport_v6.0.0'];
            addpath(nlxDDir)
            % Cd to data location
            cd('D:\homeDataTsets\nwbNLX')
            % Read NWB
            app.nwbFile = nwbRead('MLD1_Session_6_raw.nwb');
            % Load first macro wire
            macLOAD = uiprogressdlg(app.UIFigure,'Title','Loading Macro data',...
                                    'Indeterminate','on');
            macroDat = app.nwbFile.acquisition.get('MacroWireSeries').data.load();
            close(macLOAD)
            
            app.macroDATl = macroDat(5,:);
            
            app.dat2plot = app.macroDATl;
            
            plot(app.UIAxes,app.dat2plot)
            
            app.ApplyADbitButton.Enable = 'on';
            
        end

        % Button pushed function: ApplyADbitButton
        function ApplyADbitButtonPushed(app, event)
            
            % app.nwbFile.general_extracellular_ephys_electrodes.vectordata.get('filtering').data.load()
            
            % Convert with ADbit value
            adbitVal = app.nwbFile.acquisition.get('MacroWireSeries').data_conversion;
            macroDATla = app.macroDATl*adbitVal;
            
            app.dat2plot = macroDATla;
            
            plot(app.UIAxes,app.dat2plot)
            
            app.ApplyADbitButton.Enable = 'off';
            app.FixyaxisButton.Enable = 'on';
            

        end

        % Button pushed function: FixyaxisButton
        function FixyaxisButtonPushed(app, event)
            ylabel(app.UIAxes,'microVolts')

            yMIN = abs(min(app.dat2plot));
            yMAX = abs(max(app.dat2plot));

            if yMIN > yMAX
                ylim(app.UIAxes,[-1*yMIN yMIN])
            else
                ylim(app.UIAxes,[-1*yMAX yMAX])
            end
            
            app.FixyaxisButton.Enable = 'off';
            app.FixxaxisButton.Enable = 'on';
            
        end

        % Button pushed function: FixxaxisButton
        function FixxaxisButtonPushed(app, event)
            

            % Need time axis
            FS = app.nwbFile.acquisition.get('MacroWireSeries').starting_time_rate;
            specS = 0:1/FS:(length(app.dat2plot)-1)/FS;

            tsSeries = timeseries(transpose(app.dat2plot),transpose(specS));
            tsSeries.TimeInfo.Units = 'seconds';

            plot(app.UIAxes, tsSeries.Time, tsSeries.Data);

            ylabel(app.UIAxes,'microVolts')

            yMIN = abs(min(app.dat2plot));
            yMAX = abs(max(app.dat2plot));

            if yMIN > yMAX
                ylim(app.UIAxes,[-1*yMIN yMIN])
            else
                ylim(app.UIAxes,[-1*yMAX yMAX])
            end

            xlabel(app.UIAxes,'Time in seconds')
            % Fit to length of plot
            xlim(app.UIAxes, [0 max(tsSeries.Time)])
            
            app.FixxaxisButton.Enable = 'off';
            app.powrSpecButton.Enable = 'on';
            
        end

        % Button pushed function: powrSpecButton
        function powrSpecButtonPushed(app, event)
            
            app.mw1 = timetable(transpose(app.dat2plot),'SampleRate',4000,'VariableName',"MW1");

            [pxx,f] = pspectrum(app.mw1,"FrequencyLimits",[0 100]);
            plot(app.UIAxes,f,pow2db(pxx),'LineWidth',2)
            xlabel(app.UIAxes,'Frequency (Hz)')
            ylabel(app.UIAxes,'PowerSpec (dB)')
            xlim(app.UIAxes,[min(f) max(f)])
            ylim(app.UIAxes,[min(pow2db(pxx)) max(pow2db(pxx))])
            
            app.powrSpecButton.Enable = 'off';
            app.SpectrogramButton.Enable = 'on';

        end

        % Button pushed function: SpectrogramButton
        function SpectrogramButtonPushed(app, event)
 
            [S,F,T] = pspectrum(transpose(app.dat2plot),4000,'spectrogram', 'FrequencyLimits',[0 50],'MinThreshold',-110);

            imagesc(app.UIAxes2, T, flipud(F), rot90(log(abs(S')))); %plot the log spectrum
            set(app.UIAxes2,'YDir', 'normal'); % flip the Y Axi
            
            app.UIAxes2.XLim = [min(T) max(T)];
            app.UIAxes2.YLim = [min(F) max(F)];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ApplyADbitButton = uibutton(app.UIFigure, 'push');
            app.ApplyADbitButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyADbitButtonPushed, true);
            app.ApplyADbitButton.Enable = 'off';
            app.ApplyADbitButton.Text = 'Apply ADbit';
            app.FixyaxisButton = uibutton(app.UIFigure, 'push');
            app.FixyaxisButton.ButtonPushedFcn = createCallbackFcn(app, @FixyaxisButtonPushed, true);
            app.FixyaxisButton.Enable = 'off';
            app.FixyaxisButton.Text = 'Fix y axis';
            app.FixxaxisButton = uibutton(app.UIFigure, 'push');
            app.FixxaxisButton.ButtonPushedFcn = createCallbackFcn(app, @FixxaxisButtonPushed, true);
            app.FixxaxisButton.Enable = 'off';
            app.FixxaxisButton.Text = 'Fix x axis';
            app.powrSpecButton = uibutton(app.UIFigure, 'push');
            app.powrSpecButton.ButtonPushedFcn = createCallbackFcn(app, @powrSpecButtonPushed, true);
            app.powrSpecButton.Enable = 'off';
            app.powrSpecButton.Text = 'powrSpec';
            app.SpectrogramButton = uibutton(app.UIFigure, 'push');
            app.SpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramButtonPushed, true);
            app.SpectrogramButton.Enable = 'off';
            app.SpectrogramButton.Text = 'Spectrogram';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nlxephys

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end